classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        invcreptionButton        matlab.ui.control.Button
        encreptionButton         matlab.ui.control.Button
        inputdataTextArea        matlab.ui.control.TextArea
        inputdataTextAreaLabel   matlab.ui.control.Label
        keyTextArea              matlab.ui.control.TextArea
        keyTextAreaLabel         matlab.ui.control.Label
        ciphertextTextArea       matlab.ui.control.TextArea
        ciphertextTextAreaLabel  matlab.ui.control.Label
        outputTextArea           matlab.ui.control.TextArea
        outputTextAreaLabel      matlab.ui.control.Label
        TextAreaLabel            matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % sbox lookup table. Indexing is (input+1)
        sbox_in = [99	124	119	123	242	107	111	197	48	1	103	43	254	215	171	118	202	130	201	125	250	89	71	240	173	212	162	175	156	164	114	192	183	253	147	38	54	63	247	204	52	165	229	241	113	216	49	21	4	199	35	195	24	150	5	154	7	18	128	226	235	39	178	117	9	131	44	26	27	110	90	160	82	59	214	179	41	227	47	132	83	209	0	237	32	252	177	91	106	203	190	57	74	76	88	207	208	239	170	251	67	77	51	133	69	249	2	127	80	60	159	168	81	163	64	143	146	157	56	245	188	182	218	33	16	255	243	210	205	12	19	236	95	151	68	23	196	167	126	61	100	93	25	115	96	129	79	220	34	42	144	136	70	238	184	20	222	94	11	219	224	50	58	10	73	6	36	92	194	211	172	98	145	149	228	121	231	200	55	109	141	213	78	169	108	86	244	234	101	122	174	8	186	120	37	46	28	166	180	198	232	221	116	31	75	189	139	138	112	62	181	102	72	3	246	14	97	53	87	185	134	193	29	158	225	248	152	17	105	217	142	148	155	30	135	233	206	85	40	223	140	161	137	13	191	230	66	104	65	153	45	15	176	84	187	22	0];
        
        
        % inv_sbox lookup table. Indexing is (input+1)
        inv_sbox_in = [82	9	106	213	48	54	165	56	191	64	163	158	129	243	215	251
            124	227	57	130	155	47	255	135	52	142	67	68	196	222	233	203
            84	123	148	50	166	194	35	61	238	76	149	11	66	250	195	78
            8	46	161	102	40	217	36	178	118	91	162	73	109	139	209	37
            114	248	246	100	134	104	152	22	212	164	92	204	93	101	182	146
            108	112	72	80	253	237	185	218	94	21	70	87	167	141	157	132
            144	216	171	0	140	188	211	10	247	228	88	5	184	179	69	6
            208	44	30	143	202	63	15	2	193	175	189	3	1	19	138	107
            58	145	17	65	79	103	220	234	151	242	207	206	240	180	230	115
            150	172	116	34	231	173	53	133	226	249	55	232	28	117	223	110
            71	241	26	113	29	41	197	137	111	183	98	14	170	24	190	27
            252	86	62	75	198	210	121	32	154	219	192	254	120	205	90	244
            31	221	168	51	136	7	199	49	177	18	16	89	39	128	236	95
            96	81	127	169	25	181	74	13	45	229	122	159	147	201	156	239
            160	224	59	77	174	42	245	176	200	235	187	60	131	83	153	97
            23	43	4	126	186	119	214	38	225	105	20	99	85	33	12	125];
        
        %global Constants
        m = 8; % GF(2^m)
        prim_poly = 283;
        
        %MixColumn Layer matrix
        fixM = [02 03 01 01;
            01 02 03 01;
            01 01 02 03;
            03 01 01 02];
        %Inv MixColumn Layer matrix
        fixM_d = [14 11 13 09;
            09 14 11 13;
            13 09 14 11;
            11 13 09 14];
        
    end
    
    methods (Access = private)
        
        function setGlobal(app,val1,val2)
            global x
            global y
            x = val1;
            y = val2;
        end
        function [val1, val2] = getGlobal(app)
            global x
            global y
            val1 = x;
            val2 = y;
        end
        function g = g_function(app,w, r)
            
            % Shifting 矩阵行右移三
            w1 = circshift(w,[0,3]);
            
            % Byte Substitution
            out_byte = byte_subs(app,w,'e');
            
            % Round coefficient values.
            switch (r)
                case 1
                    con = 1;
                case 2
                    con = 2;
                case 3
                    con = 4;
                case 4
                    con = 8;
                case 5
                    con = 16;
                case 6
                    con = 32;
                case 7
                    con = 64;
                case 8
                    con = 128;
                case 9
                    con = 27;
                case 10
                    con = 54;
                otherwise
                    disp('Maximum round exceeded.')
            end
            % Output
            g = [bitxor(out_byte(0+1),con) out_byte(1+1) out_byte(2+1) out_byte(3+1)];
            
        end
        function [DecOutput] = gf2dec(app,GFInput,m,prim_poly)
            GFInput = GFInput(:)';% force a row vector
            GFRefArray = gf([0:(2^m)-1],m,prim_poly);
            for i=1:length(GFInput)
                for k=0:(2^m)-1
                    temp = isequal(GFInput(i),GFRefArray(k+1));
                    if (temp==1)
                        DecOutput(i) = k;
                    end
                end
            end
        end
        function out_byte = byte_subs(app, inputdata, encoding )
            %BYTE_SUBS Summary of this function goes here
            %   Detailed explanation goes here
            
            [sbox_table,inv_sbox_table] = getGlobal(app);
            if(encoding == 'e')
                % Byte substitution
                for i = 0:length(inputdata)-1
                    hexnum = dec2hex(inputdata(i+1));
                    if (length(hexnum) == 1)
                        hexnum = cat(2,'0', hexnum);
                    end
                    % Sbox lookup
                    out_byte(i+1) = sbox_table(hex2dec(hexnum(1))+1,hex2dec(hexnum(2))+1);
                end
            elseif(encoding == 'd')
                % Byte substitution
                for i = 0:length(inputdata)-1
                    hexnum = dec2hex(inputdata(i+1));
                    if (length(hexnum) == 1)
                        hexnum = cat(2,'0', hexnum);
                    end
                    % Sbox lookup
                    out_byte(i+1) = inv_sbox_table(hex2dec(hexnum(1))+1,hex2dec(hexnum(2))+1);
                end
                
            else
                msgbox('No encoding specified');
            end
            
        end
        function w = key_expansion(app,key)
            key = double(key);
            w = reshape(double(key), [4 4])';
            % round key 1-10
            for r = 1:10
                % Update w with: w[4*i] = w[4*(i-1)] xor g(x) where x = w[4*i] - 1
                g = g_function(app,w(3+1,:,(r-1)+1),r);
                w_temp = bitxor(w(0+1,:,(r-1)+1), g);
                for j = 0:3
                    % Update w with: w[4*i+j] = w[4*i+j?1] + w[4*(i?1)+j]
                    if j == 0
                        w(j+1,:,r+1) = w_temp;
                    elseif j == 1
                        w(j+1,:,r+1) = bitxor(w_temp, w(j+1,:,(r-1)+1));
                    else
                        w(j+1,:,r+1) = bitxor(w(j+1,:,(r-1)+1),w((j-1)+1,:,r+1));
                    end
                end
            end
            
        end
        function plaintext_zeros = zerofill(app,plaintext)
            if(length(plaintext) >= 1 && length(plaintext) < 16)
                for i = length(plaintext)+1:16
                    plaintext(i) = 0;
                end
            elseif(length(plaintext) > 16)
                msgbox('Input plaintext has to be between 1 and 16 characters.');
            elseif(isempty(plaintext))
                msgbox('No input plaintext given');
            end
            
            plaintext_zeros = plaintext;
            
        end
        function ciphertext = aes_encryption(app,inputdata,round_keys)
            
            %Preallocations
            encrypt = 'e'; % Encoding Mode. 'e' for encryption. 'd' for decryption.
            r = 0;
            
            % Inputs
            plaintext_dec = double(inputdata);
            
            % Initial Key Addition Layer; round (r) = 0
            inputdata = bitxor(plaintext_dec,reshape(round_keys(:,:,r+1)', [1 16]));
            for r = 1:10
                % Byte substitution
                out_byte = byte_subs(app,inputdata, encrypt);
                out_byte = reshape(out_byte, [4,4]);
                
                % ShiftRows Sublayer
                % Shifting
                for i = 1:3
                    out_byte(i+1,:) = circshift(out_byte(i+1,:),[0,3-((i+1)-2)]);
                end
                
                % MixColumn Sublayer
                if (r >= 1 && r <= 9)
                    C = gf(app.fixM,app.m,app.prim_poly) * gf(out_byte,app.m,app.prim_poly);
                    C = gf2dec(app,C,8,app.prim_poly);
                else
                    C = reshape(out_byte, [1 16]);
                end
                
                % Key Addition Layer
                inputdata = bitxor(C,reshape(round_keys(:,:,r+1)', [1 16]));
                
            end
            
            % Ciphertext
            ciphertext = inputdata;
            
            
        end
        function plaintext_recov = aes_decryption(app,ciphertext,round_keys)
            
            % Preallocations
            
            decrypt = 'd'; % Encoding Mode. 'e' for encryption. 'd' for decryption.
            r = 10; % initial decryption round
            
            % Initial Key Addition Layer; round (r) = 10
            input = bitxor(ciphertext,reshape(round_keys(:,:,r+1)', [1 16]));
            
            while(r >= 1 )
                
                % MixColumn Sublayer
                if (r <= 9 && r >= 1)
                    C = reshape(input, [4 4]);
                    B = gf(app.fixM_d,app.m,app.prim_poly) * gf(C,app.m,app.prim_poly);
                    B = gf2dec(app,B,8,app.prim_poly);
                end
                
                % Inv ShiftRows Sublayer
                % Shifting
                if(r == 10)
                    input = reshape(input, [4 4]);
                    for i = 1:3
                        input(i+1,:) = circshift(input(i+1,:),[0,i]);
                    end
                    % Initial Inv Byte substitution
                    out_byte = byte_subs(app,reshape(input,[1 16]),decrypt);
                else
                    B = reshape(B, [4 4]);
                    for i = 1:3
                        B(i+1,:) = circshift(B(i+1,:),[0,i]);
                    end
                    %Inv Byte substitution
                    out_byte = byte_subs(app,reshape(B,[1 16]),decrypt);
                end
                
                % Key Addition Layer
                input = bitxor(out_byte,reshape(round_keys(:,:,(r-1)+1)', [1 16]));
                r = r - 1;
            end
            
            plaintext_recov = input;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: encreptionButton
        function encreptionButtonPushed(app, event)
            sbox_table = transpose(reshape(app.sbox_in(1:256), [16,16]));
            inv_sbox_table = reshape(app.inv_sbox_in(1:256), [16,16]);
            setGlobal(app,sbox_table, inv_sbox_table);
            
            inputdata = double(char(app.inputdataTextArea.Value)); 
            inputdata=zerofill(app,inputdata);
            
            key=double(char(app.keyTextArea.Value));
            
            key=zerofill(app,key);
           
            round_keys = key_expansion(app,double(key));
            
            ciphertext = aes_encryption(app,inputdata,round_keys);
            
            ctext=char(ciphertext);
            app.ciphertextTextArea.Value={ctext};
            
            gr=reshape(num2cell(ciphertext),[4,4])';
            gr=cell2mat(gr);
            app.TextArea.Value={gr};
           
        end

        % Button pushed function: invcreptionButton
        function invcreptionButtonPushed(app, event)
            sbox_table = transpose(reshape(app.sbox_in(1:256), [16,16]));
            inv_sbox_table = reshape(app.inv_sbox_in(1:256), [16,16]);
            setGlobal(app,sbox_table, inv_sbox_table);
            
            key=double(char(app.keyTextArea.Value));
            key=zerofill(app,key);
            round_keys = key_expansion(app,double(key));
            
            ciphertest=double(char(app.ciphertextTextArea.Value));
            outputdata =char(aes_decryption(app,ciphertest, round_keys));
            app.outputTextArea.Value={outputdata};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.invcreptionButton = uibutton(app.UIFigure, 'push');
            app.invcreptionButton.ButtonPushedFcn = createCallbackFcn(app, @invcreptionButtonPushed, true);
            app.invcreptionButton.Text = 'invcreption';
            app.encreptionButton = uibutton(app.UIFigure, 'push');
            app.encreptionButton.ButtonPushedFcn = createCallbackFcn(app, @encreptionButtonPushed, true);
            app.encreptionButton.Text = 'encreption';
            app.inputdataTextArea = uitextarea(app.UIFigure);
            app.inputdataTextAreaLabel = uilabel(app.UIFigure);
            app.inputdataTextAreaLabel.Text = 'inputdata';
            app.keyTextArea = uitextarea(app.UIFigure);
            app.keyTextAreaLabel = uilabel(app.UIFigure);
            app.keyTextAreaLabel.Text = 'key';
            app.ciphertextTextArea = uitextarea(app.UIFigure);
            app.ciphertextTextArea.Enable = 'off';
            app.ciphertextTextAreaLabel = uilabel(app.UIFigure);
            app.ciphertextTextAreaLabel.Enable = 'off';
            app.ciphertextTextAreaLabel.Text = 'ciphertext';
            app.outputTextArea = uitextarea(app.UIFigure);
            app.outputTextArea.Enable = 'off';
            app.outputTextAreaLabel = uilabel(app.UIFigure);
            app.outputTextAreaLabel.Enable = 'off';
            app.outputTextAreaLabel.Text = 'output';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end